classdef Eye_tracker_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        CameraModeCheckBox    matlab.ui.control.CheckBox
        Image                 matlab.ui.control.Image
        CenterButton          matlab.ui.control.Button
        OutputCheckBox        matlab.ui.control.CheckBox
        BoxSizeSlider         matlab.ui.control.Slider
        BoxSizeSliderLabel    matlab.ui.control.Label
        MP4SimulateCheckBox   matlab.ui.control.CheckBox
        ThresSlider           matlab.ui.control.Slider
        ThresSliderLabel      matlab.ui.control.Label
        StartTrackButton      matlab.ui.control.Button
        ThresholdCheckBox     matlab.ui.control.CheckBox
        RightPanel            matlab.ui.container.Panel
        DesignedbyMoonLLabel  matlab.ui.control.Label
        SRLabel               matlab.ui.control.Label
        UIAxes2               matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        vidObj
        terminate_while = 0;
        update_monitor=0;
        daqs
        handel_image
        centerx=150;
        centery = 150;
        box_size = 40;
        pupil_size = 15;
        X
        Y
        X_ratio=0;
        Y_ratio=0;
        voltage_gain = 5;
        fresh_int = 10
    end
    
    methods (Access = private)
        function subplotCallback(app,~,eventdata)
            if(eventdata.Button==1) % left click
                app.centerx = floor(eventdata.IntersectionPoint(2));
                app.centery = floor(eventdata.IntersectionPoint(1));
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.UIAxes, 'off');
            title(app.UIAxes,'Camera Image')
            %hold(app.UIAxes, 'on');
            videoFile = '85c97f514e837841c956e55d7142bdd4.mp4'; % 替换为你的MP4文件路径
            app.vidObj = VideoReader(videoFile);
            try
                app.daqs = daq.createSession('ni');
                addAnalogOutputChannel(app.daqs, 'Dev1', 'ao0', 'Voltage'); % 输出x坐标
                addAnalogOutputChannel(app.daqs, 'Dev1', 'ao1', 'Voltage'); % 输出y坐标
            catch
                release(app.daqs)
            end
            imshow(rand(300),'Parent',app.UIAxes)
        end

        % Button pushed function: StartTrackButton
        function StartTrackButtonPushed(app, event)

            while(~app.terminate_while)
            tic
            if(app.MP4SimulateCheckBox.Value)
                if hasFrame(app.vidObj)
                    img = readFrame(app.vidObj);
                    %img = imresize(img,[200,200]);
                else
                    videoFile = '85c97f514e837841c956e55d7142bdd4.mp4'; % 替换为你的MP4文件路径
                    app.vidObj = VideoReader(videoFile);
                    img = readFrame(app.vidObj);
                    %img = imresize(img,[200,200]);
                end
            else
                % load camera data
            end
                    % transform the image in a BW image
                    if size(img,3)==3
                        g_image=rgb2gray(img);
                    end

                    cropX = max(1,floor(app.centerx - app.box_size));
                    cropY = max(1,floor(app.centery - app.box_size));

                    bw_image = ~imbinarize(g_image,app.ThresSlider.Value); 
                    crop_bw_image = imcrop(bw_image, [cropY, cropX, 2*app.box_size, 2*app.box_size]);

                    fill_image=imfill(crop_bw_image,'holes'); 
                    findout_area=regionprops(fill_image);
                    areas=[findout_area.Area];
                    [~, pam_max]=max(areas);
                
                    % check which image to show
                    if(app.ThresholdCheckBox.Value)
                        im_to_show = bw_image;
                        eye_color = 0;
                    else
                        im_to_show = g_image;
                        eye_color = 255;
                    end

                    % judge eye location
                    if(isempty(pam_max))
                        app.X = app.centerx-app.box_size;
                        app.Y = app.centery-app.box_size;
                    else
                        centro=findout_area(pam_max).Centroid;
                        app.X=centro(2)+app.centerx-app.box_size;
                        app.Y=centro(1)+app.centery-app.box_size;
                    end

                    % send eye location
                    if(app.OutputCheckBox.Value)
                        app.X_ratio = -app.voltage_gain*(centro(2)-app.box_size)./app.box_size+2.5;
                        app.X_ratio = max(min(app.X_ratio,4.9),0.1);
                        app.Y_ratio = app.voltage_gain*(centro(1)-app.box_size)./app.box_size+2.5;
                        app.Y_ratio = max(min(app.Y_ratio,4.9),0.1);
                        outputSingleScan(app.daqs, [app.X_ratio,app.Y_ratio]);
                    end
                    
                    % update figure
                    if(mod(app.update_monitor,app.fresh_int)==0)
                        % add center
                        img_pos_X = round(app.X); img_pos_Y = round(app.Y);
                            im_to_show(app.centerx-app.box_size,app.centery-app.box_size:app.centery+app.box_size)=1 ;
                            im_to_show(app.centerx+app.box_size,app.centery-app.box_size:app.centery+app.box_size)=1;
                            im_to_show(app.centerx-app.box_size:app.centerx+app.box_size,app.centery-app.box_size)=1 ;
                            im_to_show(app.centerx-app.box_size:app.centerx+app.box_size,app.centery+app.box_size)=1;
                        % add box
                            im_to_show(img_pos_X, img_pos_Y-app.pupil_size:img_pos_Y+app.pupil_size)=eye_color;
                            im_to_show(img_pos_X-app.pupil_size:img_pos_X+app.pupil_size,img_pos_Y)=eye_color;

                        app.handel_image = imshow(im_to_show, 'Parent',app.UIAxes);
                        set(app.handel_image, 'ButtonDownFcn', @(src, event) subplotCallback(app,src,event));
                        app.update_monitor=1;
                        %rectangle(app.UIAxes, 'Position', [app.centery-app.box_size, app.centerx-app.box_size, app.box_size*2, app.box_size*2]);
                    end
                    plot(app.UIAxes2, app.Y_ratio, app.X_ratio, 'r.','MarkerSize',40)
                    drawnow
                    app.SRLabel.Text=sprintf('FreshInterVal = %.02f ms, X = %.02fv, Y =  %.02fv', 1000*toc, app.X_ratio, app.Y_ratio);
                    app.update_monitor = app.update_monitor+1;
            end
            app.terminate_while=0;
        end

        % Value changed function: BoxSizeSlider, CameraModeCheckBox, 
        % ...and 4 other components
        function AnyValueChanged(app, event)
            app.terminate_while=1;
            if(app.CameraModeCheckBox.Value)
                app.fresh_int = 1;
            else
                app.fresh_int = 5;
            end
            app.box_size = floor(app.BoxSizeSlider.Value);
            StartTrackButtonPushed(app)
        end

        % Button pushed function: CenterButton
        function CenterButtonPushed(app, event)
                app.terminate_while=1;
                app.centerx = floor(app.X);
                app.centery = floor(app.Y);
                StartTrackButtonPushed(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {704, 704};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {210, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ThresholdCheckBox = uicheckbox(app.LeftPanel);
            app.ThresholdCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.ThresholdCheckBox.Text = 'Threshold';
            app.StartTrackButton = uibutton(app.LeftPanel, 'push');
            app.StartTrackButton.ButtonPushedFcn = createCallbackFcn(app, @StartTrackButtonPushed, true);
            app.StartTrackButton.Text = 'StartTrack';
            app.ThresSliderLabel = uilabel(app.LeftPanel);
            app.ThresSliderLabel.Text = 'Thres';
            app.ThresSlider = uislider(app.LeftPanel);
            app.ThresSlider.Limits = [0 0.3];
            app.ThresSlider.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.ThresSlider.Value = 0.1;
            app.MP4SimulateCheckBox = uicheckbox(app.LeftPanel);
            app.MP4SimulateCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.MP4SimulateCheckBox.Text = 'MP4 Simulate';
            app.MP4SimulateCheckBox.Value = true;
            app.BoxSizeSliderLabel = uilabel(app.LeftPanel);
            app.BoxSizeSliderLabel.Text = 'BoxSize';
            app.BoxSizeSlider = uislider(app.LeftPanel);
            app.BoxSizeSlider.Limits = [10 70];
            app.BoxSizeSlider.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.BoxSizeSlider.Value = 30;
            app.OutputCheckBox = uicheckbox(app.LeftPanel);
            app.OutputCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.OutputCheckBox.Text = 'Output';
            app.CenterButton = uibutton(app.LeftPanel, 'push');
            app.CenterButton.ButtonPushedFcn = createCallbackFcn(app, @CenterButtonPushed, true);
            app.CenterButton.Text = 'Center';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'LAB_logo.jpg');
            app.CameraModeCheckBox = uicheckbox(app.LeftPanel);
            app.CameraModeCheckBox.ValueChangedFcn = createCallbackFcn(app, @AnyValueChanged, true);
            app.CameraModeCheckBox.Text = 'Camera Mode';
            app.CameraModeCheckBox.Value = true;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Location')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [0 5];
            app.UIAxes2.YLim = [0 5];
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ZTick = [];
            app.SRLabel = uilabel(app.RightPanel);
            app.SRLabel.Text = 'SR';
            app.DesignedbyMoonLLabel = uilabel(app.RightPanel);
            app.DesignedbyMoonLLabel.Text = 'Designed by MoonL';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Eye_tracker_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end